classdef image_filtering < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ConvolutionMenu               matlab.ui.container.Menu
        ImageSmoothingMenu            matlab.ui.container.Menu
        EdgeDetectionMenu             matlab.ui.container.Menu
        ImageBrigtheningMenu          matlab.ui.container.Menu
        NoiseFilteringMenu            matlab.ui.container.Menu
        PeriodicNoiseRestorationMenu  matlab.ui.container.Menu
        MotionBlurMenu                matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        PanelGrid                     matlab.ui.container.GridLayout
        Type2DropDown                 matlab.ui.control.DropDown
        DropDown2Label                matlab.ui.control.Label
        Type1DropDown                 matlab.ui.control.DropDown
        DropDownLabel                 matlab.ui.control.Label
        Input3EditFieldLabel          matlab.ui.control.Label
        Input3EditField               matlab.ui.control.NumericEditField
        Input2EditField               matlab.ui.control.NumericEditField
        Input2EditFieldLabel          matlab.ui.control.Label
        Input1EditField               matlab.ui.control.NumericEditField
        Input1EditFieldLabel          matlab.ui.control.Label
        ExecuteButton                 matlab.ui.control.Button
        LogArea                       matlab.ui.control.TextArea
        SystemLogTextAreaLabel        matlab.ui.control.Label
        SelectedImageLabel            matlab.ui.control.Label
        BrowseButtonLabel             matlab.ui.control.Label
        BrowseButton                  matlab.ui.control.Button
        InfoMask                      matlab.ui.control.Label
        InputMaskEditField            matlab.ui.control.EditField
        InputMaskEditFieldLabel       matlab.ui.control.Label
        ConfigLabel                   matlab.ui.control.Label
        DisplayGrid                   matlab.ui.container.GridLayout
        InputGrid                     matlab.ui.container.GridLayout
        InputImageGrid                matlab.ui.container.GridLayout
        InputImage                    matlab.ui.control.Image
        InputImageLabel               matlab.ui.control.Label
        InputFourierGrid              matlab.ui.container.GridLayout
        InputFourier                  matlab.ui.control.Image
        InputFourierLabel             matlab.ui.control.Label
        ResultsGrid                   matlab.ui.container.GridLayout
        ResultGrid1                   matlab.ui.container.GridLayout
        ImageResult1                  matlab.ui.control.Image
        Output1Label                  matlab.ui.control.Label
        ResultGrid2                   matlab.ui.container.GridLayout
        ImageResult2                  matlab.ui.control.Image
        Output2Label                  matlab.ui.control.Label
        ResultGrid3                   matlab.ui.container.GridLayout
        Output3Label                  matlab.ui.control.Label
        ImageResult3                  matlab.ui.control.Image
        ResultGrid4                   matlab.ui.container.GridLayout
        ImageResult4                  matlab.ui.control.Image
        Output4Label                  matlab.ui.control.Label
        ResultGrid5                   matlab.ui.container.GridLayout
        Output5Label                  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    properties (Access = public)
        IMAGE_INPUT = [];
        FOURIER_INPUT = [];
        IMAGE_RESULT_1 = [];
        IMAGE_RESULT_2 = [];
        IMAGE_RESULT_3 = [];
        IMAGE_RESULT_4 = [];
        AXES_VALUE = [];

        MASK_VALUE_STR = "";
        MASK_VALUE
        N_MASK
        LOG_STR = "";
    end
    
    methods (Access = public)
        function print_config_on_system_log(app)
            app.LogArea.Value = "";
            app.LOG_STR = "";
            if (app.Type1DropDown.Visible == "on")
                app.LOG_STR = app.LOG_STR + app.DropDownLabel.Text + " Value: "+ app.Type1DropDown.Value + newline;
            end
            if (app.Type2DropDown.Visible == "on")
                app.LOG_STR = app.LOG_STR + app.DropDown2Label.Text + " Value: "+ app.Type2DropDown.Value + newline;
            end
            if (app.Input1EditField.Visible == "on")
                app.LOG_STR = app.LOG_STR + app.Input1EditFieldLabel.Text + " Value: "+ app.Input1EditField.Value + newline;
            end
            if (app.Input2EditField.Visible == "on")
                app.LOG_STR = app.LOG_STR + app.Input2EditFieldLabel.Text + " Value: "+ app.Input2EditField.Value + newline;
            end
            if (app.Input3EditField.Visible == "on")
                app.LOG_STR = app.LOG_STR + app.Input3EditFieldLabel.Text + " Value: "+ app.Input3EditField.Value + newline;
            end
            app.LogArea.Value = app.LOG_STR;
            drawnow;
        end

        function results = append_string_newline_and_print(app, str_initial, str_append)
            results = str_initial + newline + str_append;
            app.LogArea.Value = results;
            drawnow;
        end

        function display_image_on_ui(~, img, ui)
            if (size(img, 3) == 1)
                grayImage = img;
                image_to_display = cat(3, grayImage, grayImage, grayImage);
            else
                image_to_display = img;
            end
            ui.ImageSource = image_to_display;
        end

        function set_panel_config(app, config)
            if (ismember(1, config))
                app.InfoMask.Visible = "on";
                app.InputMaskEditField.Visible = "on";
                app.InputMaskEditFieldLabel.Visible = "on";
            else
                app.InfoMask.Visible = "off";
                app.InputMaskEditField.Visible = "off";
                app.InputMaskEditFieldLabel.Visible = "off";
            end

            if(ismember(2,config))
                app.Type1DropDown.Visible = "on";
                app.DropDownLabel.Visible="on";
            else
                app.Type1DropDown.Visible = "off";
                app.DropDownLabel.Visible="off";
            end

            if(ismember(3,config))
                app.Type2DropDown.Visible = "on";
                app.DropDown2Label.Visible="on";
            else
                app.Type2DropDown.Visible = "off";
                app.DropDown2Label.Visible="off";
            end

            if(ismember(4,config))
                app.Input1EditField.Visible = "on";
                app.Input1EditFieldLabel.Visible = "on";
            else
                app.Input1EditField.Visible = "off";
                app.Input1EditFieldLabel.Visible = "off";
            end

            if(ismember(5,config))
                app.Input2EditField.Visible = "on";
                app.Input2EditFieldLabel.Visible = "on";
            else
                app.Input2EditField.Visible = "off";
                app.Input2EditFieldLabel.Visible = "off";
            end

            if(ismember(6,config))
                app.Input3EditField.Visible = "on";
                app.Input3EditFieldLabel.Visible = "on";
            else
                app.Input3EditField.Visible = "off";
                app.Input3EditFieldLabel.Visible = "off";
            end
        end


        function set_result_config(app, config)
            columnWidth = {};
            for r=1:5
                if (ismember(r, config))
                    if (r == 5)
                        columnWidth = [columnWidth, {'3x'}];
                    else
                        columnWidth = [columnWidth, {'2x'}];
                    end
                else
                    columnWidth = [columnWidth, {0}];
                end
            end
            app.ResultsGrid.ColumnWidth = columnWidth;

            if (ismember(1, config))
                app.ImageResult1.Visible = "on";
                app.Output1Label.Visible = "on";
            else
                app.ImageResult1.Visible = "off";
                app.Output1Label.Visible = "off";
            end

            if (ismember(2, config))
                app.ImageResult2.Visible = "on";
                app.Output2Label.Visible = "on";
            else
                app.ImageResult2.Visible = "off";
                app.Output2Label.Visible = "off";
            end

            if (ismember(3, config))
                app.ImageResult3.Visible = "on";
                app.Output3Label.Visible = "on";
            else
                app.ImageResult3.Visible = "off";
                app.Output3Label.Visible = "off";
            end


            if (ismember(4, config))
                app.ImageResult4.Visible = "on";
                app.Output4Label.Visible = "on";
            else
                app.ImageResult4.Visible = "off";
                app.Output4Label.Visible = "off";
            end

            if (ismember(5, config))
                app.UIAxes.Visible = "on";
                app.Output5Label.Visible = "on";
            else
                app.UIAxes.Visible = "off";
                app.Output5Label.Visible = "off";
            end
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: ConvolutionMenu
        function ConvolutionMenuSelected(app, event)
            app.ConfigLabel.Text = 'Convolution';
            set_panel_config(app,[1]);
            set_result_config(app,[1,2]);

            app.Output2Label.Text = "Result Image (Lib)";
        end

        % Menu selected function: ImageSmoothingMenu
        function ImageSmoothingMenuSelected(app, event)
            app.ConfigLabel.Text = 'Image Smoothing';
            set_panel_config(app,[2,3,4]);

            app.DropDownLabel.Text = "Smoothing Domain";
            app.Type1DropDown.Items = ["Spatial", "Frequency"];
            app.Type1DropDown.Value = "Spatial";
            app.DropDown2Label.Text = "Smoothing Type";
            app.Type2DropDown.Items = ["Mean", "Gaussian"];
            app.Type2DropDown.Value = "Mean";

            app.Input1EditFieldLabel.Text = "n Mask";
            app.Input1EditField.Value = 1;
            set_result_config(app, [1]);
        end

        % Menu selected function: NoiseFilteringMenu
        function NoiseFilteringMenuSelected(app, event)
            app.ConfigLabel.Text = 'Noise Filtering';

            set_panel_config(app,[2,3,4,5]);
            app.DropDownLabel.Text = "Noise Type";
            app.Type1DropDown.Items = ["Salt and Pepper", "Gaussian"];
            app.Type1DropDown.Value = "Salt and Pepper";

            app.DropDown2Label.Text = "Filter Type";
            app.Type2DropDown.Items = ["Min", "Max", "Median", "Arithmetic Mean", "Geometric Mean", "Harmonic Mean", "Contraharmonic Mean", "Midpoint", "Alpha-trimmed Mean"];
            app.Type2DropDown.Value = "Min";

            app.Input1EditFieldLabel.Text = "n Window (Square)";
            app.Input1EditField.Value = 3;

            app.Input2EditFieldLabel.Text = "Density";
            app.Input2EditField.Value = 0.02;

            set_result_config(app, [1,2]);
            app.Output1Label.Text = "Restored Image";
            app.Output2Label.Text = "Image with Noise";

        end

        % Menu selected function: PeriodicNoiseRestorationMenu
        function PeriodicNoiseRestorationMenuSelected(app, event)
            app.ConfigLabel.Text = 'Periodic Noise Restoration';

            set_panel_config(app,[2,4,5]);
            app.DropDownLabel.Text = "Filter Type";
            app.Type1DropDown.Items = ["Bandreject", "Bandpass", "Notch"];
            app.Type1DropDown.Value = "Bandreject";

            app.Input1EditFieldLabel.Text = "D0 Distance";
            app.Input1EditField.Value = 0;
            app.Input2EditFieldLabel.Text = "W (Width)";
            app.Input2EditField.Value = 0;

            set_result_config(app, [1,2,5]);
            app.Output1Label.Text = "Restored Image";
            app.Output2Label.Text = "Mesh Arrays";
            app.Output5Label.Text = "Mesh Graph";


        end

        % Menu selected function: MotionBlurMenu
        function MotionBlurMenuSelected(app, event)
            app.ConfigLabel.Text = 'Motion Deconvolution';

            set_panel_config(app,[4,5,6]);
            app.Input1EditFieldLabel.Text = "Length";
            app.Input1EditField.Value = 10;

            app.Input2EditFieldLabel.Text = "Angle (Theta)";
            app.Input2EditField.Value = 0;

            app.Input3EditFieldLabel.Text = "Noise Var";
            app.Input3EditField.Value = 0.0001;

            set_result_config(app, [1,2,3]);
            app.Output1Label.Text = "Restored Image";
            app.Output2Label.Text = "Image with Motion Blur";
            app.Output3Label.Text = "Original vs Restored";

        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            addpath("functions\utils\");
            % Open file dialog to browse for an image file
            [fileName, filePath] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'});
            
            % If the user selected a file, display it in the UIAxes
            if ischar(fileName)
                % Full file path of the selected image
                fullFileName = fullfile(filePath, fileName);

                % Change label
                app.SelectedImageLabel.Text = fileName;
                app.IMAGE_INPUT= imread(fullFileName);
                
                % Display Fourier
                [row, col, n] = size(app.IMAGE_INPUT);
                [~, fourier_display] = fourier_transform(row, col, n, app.IMAGE_INPUT);
                app.FOURIER_INPUT = rescale(fourier_display);

                display_image_on_ui(app, app.IMAGE_INPUT, app.InputImage);
                display_image_on_ui(app, app.FOURIER_INPUT, app.InputFourier);
            end
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            addpath("functions\matrix\");
            addpath("functions\programs\");
            app.LOG_STR = ""; % Reset System Log
            app.LogArea.Value = "";

            if (isempty(app.IMAGE_INPUT))
                app.LOG_STR = append_string_newline_and_print(app, app.LOG_STR, "[FAILED] No image is inputted!");
                return;
            else
                [row, col, ~] = size(app.IMAGE_INPUT);
                string_display = "[DISPLAY] Row: "+row +", Col: "+ col;
                app.LOG_STR = append_string_newline_and_print(app, app.LOG_STR, string_display);
            end

            if (app.ConfigLabel.Text == "Convolution")
                if (isempty(app.MASK_VALUE_STR))
                    app.LOG_STR = append_string_newline_and_print(app, app.LOG_STR, "[FAILED] Mask is empty!");
                    return;
                end

                [app.MASK_VALUE, app.N_MASK] = reshape_mask(app.MASK_VALUE_STR);
                app.LOG_STR = append_string_newline_and_print(app, app.LOG_STR, "[INITIATE] Convolution Process");
                mask_str = "[MASK] " + display_mat(app.MASK_VALUE);
                app.LOG_STR = append_string_newline_and_print(app, app.LOG_STR,  mask_str);
                app.LOG_STR = append_string_newline_and_print(app, app.LOG_STR, "[PROCESS] Process might take time. Please wait...");
                app.IMAGE_RESULT_1 = convolution(app.IMAGE_INPUT, app.MASK_VALUE, app.N_MASK);
                app.IMAGE_RESULT_2 = convolution_lib(app.IMAGE_INPUT, app.MASK_VALUE, app.N_MASK);
                
                display_image_on_ui(app, app.IMAGE_RESULT_1, app.ImageResult1);
                display_image_on_ui(app, app.IMAGE_RESULT_2, app.ImageResult2);

            elseif (app.ConfigLabel.Text == "Image Smoothing")
                app.LOG_STR = append_string_newline_and_print(app, app.LOG_STR, "[INITIATE] Image Smoothing Process");
                app.LOG_STR = append_string_newline_and_print(app, app.LOG_STR, "[PROCESS] Process might take time. Please wait...");

                if (app.Type1DropDown.Value == "Spatial")
                    app.IMAGE_RESULT_1 = image_smoothing_spatial(app.IMAGE_INPUT, app.Type2DropDown.Value, app.Input1EditField.Value, app.Input2EditField.Value);
                    display_image_on_ui(app, app.IMAGE_RESULT_1, app.ImageResult1);
                else % (app.Type1DropDown.Value == "Frequency")
                    [img_result, meshArrays] = image_smoothing_frequency(app.IMAGE_INPUT, app.Type2DropDown.Value, app.Input1EditField.Value, app.Input2EditField.Value, 0, 0); % Ignore gammaL, and gammaH
                    app.IMAGE_RESULT_1 = img_result;
                    app.IMAGE_RESULT_2 = rescale(meshArrays);
                    app.AXES_VALUE = meshArrays;

                    display_image_on_ui(app, app.IMAGE_RESULT_1, app.ImageResult1);
                    display_image_on_ui(app, app.IMAGE_RESULT_2, app.ImageResult2);
                    mesh(app.UIAxes, app.AXES_VALUE);
                end
            elseif(app.ConfigLabel.Text == "Edge Detection")
                app.LOG_STR = append_string_newline_and_print(app, app.LOG_STR, "[INITIATE] Edge Detection Process");
                app.LOG_STR = append_string_newline_and_print(app, app.LOG_STR, "[PROCESS] Process might take time. Please wait...");
                [img_result, meshArrays] = image_smoothing_frequency(app.IMAGE_INPUT, app.Type2DropDown.Value, app.Input1EditField.Value, app.Input2EditField.Value, 0, 0); % Ignore gammaL, and gammaH
                app.IMAGE_RESULT_1 = img_result;
                app.IMAGE_RESULT_2 = rescale(meshArrays);
                app.AXES_VALUE = meshArrays;

                display_image_on_ui(app, app.IMAGE_RESULT_1, app.ImageResult1);
                display_image_on_ui(app, app.IMAGE_RESULT_2, app.ImageResult2);
                mesh(app.UIAxes, app.AXES_VALUE);
            
            elseif(app.ConfigLabel.Text == "Image Brightening")
                app.LOG_STR = append_string_newline_and_print(app, app.LOG_STR, "[INITIATE] Image Brightening Process");
                app.LOG_STR = append_string_newline_and_print(app, app.LOG_STR, "[PROCESS] Process might take time. Please wait...");
                [img_result, meshArrays] = image_smoothing_frequency(app.IMAGE_INPUT, app.Type2DropDown.Value, app.Input1EditField.Value, 0, app.Input2EditField.Value, app.Input3EditField.Value); % Ignore n
                app.IMAGE_RESULT_1 = img_result;
                app.IMAGE_RESULT_2 = rescale(meshArrays);
                app.AXES_VALUE = meshArrays;

                display_image_on_ui(app, app.IMAGE_RESULT_1, app.ImageResult1);
                display_image_on_ui(app, app.IMAGE_RESULT_2, app.ImageResult2);
                mesh(app.UIAxes, app.AXES_VALUE);
                
            elseif (app.ConfigLabel.Text == "Noise Filtering")
                app.LOG_STR = append_string_newline_and_print(app, app.LOG_STR, "[INITIATE] Noise Filtering Process");
                app.LOG_STR = append_string_newline_and_print(app, app.LOG_STR, "[PROCESS] Process might take time. Please wait...");
                
                if (app.Type1DropDown.Value == "Salt and Pepper")
                    [img_result, img_with_noise] = noise_filtering(app.IMAGE_INPUT, app.Type1DropDown.Value, app.Type2DropDown.Value, app.Input1EditField.Value, app.Input2EditField.Value, 0, 0); % Ignore mean and variance
                else % Gaussian
                    [img_result, img_with_noise] = noise_filtering(app.IMAGE_INPUT, app.Type1DropDown.Value, app.Type2DropDown.Value, app.Input1EditField.Value, 0, app.Input2EditField.Value, app.Input3EditField.Value); % Ignore density
                end

                app.IMAGE_RESULT_1 = rescale(img_result);
                app.IMAGE_RESULT_2 = img_with_noise;
                display_image_on_ui(app, app.IMAGE_RESULT_1, app.ImageResult1);
                display_image_on_ui(app, app.IMAGE_RESULT_2, app.ImageResult2);

            elseif (app.ConfigLabel.Text == "Periodic Noise Restoration")
                app.LOG_STR = append_string_newline_and_print(app, app.LOG_STR, "[INITIATE] Periodic Noise Process");
                app.LOG_STR = append_string_newline_and_print(app, app.LOG_STR, "[PROCESS] Process might take time. Please wait...");
                if (app.Type1DropDown.Value == "Bandreject")
                    [img_result, meshArrays] = periodic_noise_restoration(app.IMAGE_INPUT, app.Type1DropDown.Value, app.Input2EditField.Value, app.Input1EditField.Value, 0); %Ignore D1 
                elseif (app.Type1DropDown.Value == "Bandpass")
                    [img_result, meshArrays] = periodic_noise_restoration(app.IMAGE_INPUT, app.Type1DropDown.Value, 0, app.Input1EditField.Value, app.Input2EditField.Value); %Ignore W 
                else % notch
                    [img_result, meshArrays] = periodic_noise_restoration(app.IMAGE_INPUT, app.Type1DropDown.Value, 0, app.Input1EditField.Value, 0); %Ignore W and D1
                end
                app.IMAGE_RESULT_1 = img_result;
                app.IMAGE_RESULT_2 = rescale(meshArrays);
                app.AXES_VALUE = meshArrays;

                display_image_on_ui(app, app.IMAGE_RESULT_1, app.ImageResult1);
                display_image_on_ui(app, app.IMAGE_RESULT_2, app.ImageResult2);
                mesh(app.UIAxes, app.AXES_VALUE);

            else % (app.ConfigLabel.Text == "Motion Deconvolution")
                app.LOG_STR = append_string_newline_and_print(app, app.LOG_STR, "[INITIATE] Motion Deconvolution Process");
                app.LOG_STR = append_string_newline_and_print(app, app.LOG_STR, "[PROCESS] Process might take time. Please wait...");

                [img_result, img_motion_blur, original_vs_restored] = blurring_deconvolution(app.IMAGE_INPUT, app.Input1EditField.Value, app.Input2EditField.Value, app.Input3EditField.Value);
                app.IMAGE_RESULT_1 = img_result;
                app.IMAGE_RESULT_2 = img_motion_blur;
                app.IMAGE_RESULT_3 = original_vs_restored;
                display_image_on_ui(app, app.IMAGE_RESULT_1, app.ImageResult1);
                display_image_on_ui(app, app.IMAGE_RESULT_2, app.ImageResult2);
                display_image_on_ui(app, app.IMAGE_RESULT_3, app.ImageResult3);

            end

            app.LOG_STR = append_string_newline_and_print(app, app.LOG_STR, "[FINISHED] Process has finished!");
        end

        % Value changed function: InputMaskEditField
        function InputMaskEditFieldValueChanged(app, event)
            addpath("functions\utils\");
            app.MASK_VALUE_STR = app.InputMaskEditField.Value;
        end

        % Value changed function: Type1DropDown
        function Type1DropDownValueChanged(app, event)
           
            % Handles
            if (app.ConfigLabel.Text == "Image Smoothing" && app.Type1DropDown.Value == "Spatial")
                set_panel_config(app,[2,3,4]);
                app.DropDown2Label.Text = "Smoothing Type";
                app.Type2DropDown.Items = ["Mean", "Gaussian"];
                app.Type2DropDown.Value = "Mean";

                app.Input1EditFieldLabel.Text = "n Mask";
                app.Input1EditField.Value = 1;
                set_result_config(app,[1]);

            elseif (app.ConfigLabel.Text == "Image Smoothing" && app.Type1DropDown.Value == "Frequency")
                set_panel_config(app,[2,3,4]);
                app.DropDown2Label.Text = "Frequency Type";
                app.Type2DropDown.Items = ["ILPF", "GLPF", "BLPF"];
                app.Type2DropDown.Value = "ILPF";

                app.Input1EditFieldLabel.Text = "Cutoff Frequency";
                app.Input1EditField.Value= 1;
                set_result_config(app,[1,2,5]);

                app.Output1Label.Text = "Result Image";
                app.Output2Label.Text = "Mesh Arrays";
                app.Output5Label.Text = "Mesh Graph";
            end

            if (app.ConfigLabel.Text == "Noise Filtering")
                if (app.Type1DropDown.Value == "Salt and Pepper")
                    set_panel_config(app, [2,3,4,5]);
                    app.Input2EditFieldLabel.Text = "Density";
                    app.Input2EditField.Value = 0.02;

                else % app.Type1DropDown.Value == "Gaussian"
                    set_panel_config(app, [2,3,4,5,6]);
                    app.Input2EditFieldLabel.Text = "Mean";
                    app.Input2EditField.Value = 0;
                    app.Input3EditFieldLabel.Text = "Variance";
                    app.Input3EditField.Value = 0.01;
                end
            end

            if (app.ConfigLabel.Text == "Periodic Noise Restoration")
                if (app.Type1DropDown.Value == "Bandreject")
                    set_panel_config(app, [2,4,5]);
                    app.Input1EditFieldLabel.Text = "D0 Distance";
                    app.Input1EditField.Value = 0;
                    app.Input2EditFieldLabel.Text = "W (Width)";
                    app.Input2EditField.Value = 0;
                elseif (app.Type1DropDown.Value == "Bandpass")
                    set_panel_config(app, [2,4,5]);
                    app.Input1EditFieldLabel.Text = "D0 (Low Cutoff)";
                    app.Input1EditField.Value = 0;
                    app.Input2EditFieldLabel.Text = "D1 (High Cutoff)";
                    app.Input2EditField.Value = 0;
                else
                    set_panel_config(app, [2,4]);
                    app.Input1EditFieldLabel.Text = "D0 (Low Cutoff)";
                    app.Input1EditField.Value = 0;
                end
            end

            print_config_on_system_log(app);
        end

        % Value changed function: Type2DropDown
        function Type2DropDownValueChanged(app, event)
            if (app.ConfigLabel.Text == "Image Smoothing" && app.Type1DropDown.Value == "Spatial" && app.Type2DropDown.Value == "Mean")
                set_panel_config(app, [2,3,4]);
            elseif (app.ConfigLabel.Text == "Image Smoothing" && app.Type1DropDown.Value == "Spatial" && app.Type2DropDown.Value == "Gaussian")
                set_panel_config(app, [2,3,4,5]);
                app.Input2EditFieldLabel.Text = "Sigma";
                app.Input2EditField.Value = 1;
            end

            if (app.Type1DropDown.Value == "Frequency")
                if ((app.ConfigLabel.Text == "Edge Detection" && app.Type2DropDown.Value == "BHPF") || (app.ConfigLabel.Text == "Image Smoothing" && app.Type2DropDown.Value == "BLPF"))
                    set_panel_config(app, [2,3,4,5]);
                    app.Input2EditFieldLabel.Text = "N (order)";
                    app.Input2EditField.Value = 2;
                else
                    set_panel_config(app, [2,3,4]);
                end
            end

            print_config_on_system_log(app);
        end

        % Value changed function: Input1EditField
        function Input1EditFieldValueChanged(app, event)

            print_config_on_system_log(app);
        end

        % Value changed function: Input2EditField
        function Input2EditFieldValueChanged(app, event)

            print_config_on_system_log(app);
        end

        % Menu selected function: EdgeDetectionMenu
        function EdgeDetectionMenuSelected(app, event)
            app.ConfigLabel.Text = 'Edge Detection';

            set_panel_config(app,[2,3,4]);

            app.DropDownLabel.Text = "Smoothing Domain";
            app.Type1DropDown.Items = ["Frequency"];
            app.Type1DropDown.Value = "Frequency";
            app.DropDown2Label.Text = "Smoothing Type";
            app.Type2DropDown.Items = ["IHPF", "GHPF", "BHPF"];
            app.Type2DropDown.Value = "IHPF";

            app.Input1EditFieldLabel.Text = "Cutoff Frequency";
            app.Input1EditField.Value= 1;

            set_result_config(app,[1,2,5]);

            app.Output1Label.Text = "Result Image";
            app.Output2Label.Text = "Mesh Arrays";
            app.Output5Label.Text = "Mesh Graph";
        end

        % Menu selected function: ImageBrigtheningMenu
        function ImageBrigtheningMenuSelected(app, event)
            app.ConfigLabel.Text = 'Image Brightening';

            set_panel_config(app,[2,3,4,5,6]);

            app.DropDownLabel.Text = "Smoothing Domain";
            app.Type1DropDown.Items = ["Frequency"];
            app.Type1DropDown.Value = "Frequency";
            app.DropDown2Label.Text = "Smoothing Type";
            app.Type2DropDown.Items = ["Homomorphic"];
            app.Type2DropDown.Value = "Homomorphic";

            app.Input1EditFieldLabel.Text = "Cutoff Frequency";
            app.Input1EditField.Value= 1;
            app.Input2EditFieldLabel.Text = "Gamma L";
            app.Input2EditField.Value= 2;
            app.Input3EditFieldLabel.Text = "Gamma H";
            app.Input3EditField.Value= 4;
            set_result_config(app,[1,2,5]);

            app.Output1Label.Text = "Result Image";
            app.Output2Label.Text = "Mesh Arrays";
            app.Output5Label.Text = "Mesh Graph";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConvolutionMenu = uimenu(app.UIFigure);
            app.ConvolutionMenu.MenuSelectedFcn = createCallbackFcn(app, @ConvolutionMenuSelected, true);
            app.ConvolutionMenu.Text = 'Convolution';
            app.ImageSmoothingMenu = uimenu(app.UIFigure);
            app.ImageSmoothingMenu.MenuSelectedFcn = createCallbackFcn(app, @ImageSmoothingMenuSelected, true);
            app.ImageSmoothingMenu.Text = 'Image Smoothing';
            app.EdgeDetectionMenu = uimenu(app.UIFigure);
            app.EdgeDetectionMenu.MenuSelectedFcn = createCallbackFcn(app, @EdgeDetectionMenuSelected, true);
            app.EdgeDetectionMenu.Text = 'Edge Detection';
            app.ImageBrigtheningMenu = uimenu(app.UIFigure);
            app.ImageBrigtheningMenu.MenuSelectedFcn = createCallbackFcn(app, @ImageBrigtheningMenuSelected, true);
            app.ImageBrigtheningMenu.Text = 'Image Brigthening';
            app.NoiseFilteringMenu = uimenu(app.UIFigure);
            app.NoiseFilteringMenu.MenuSelectedFcn = createCallbackFcn(app, @NoiseFilteringMenuSelected, true);
            app.NoiseFilteringMenu.Text = 'Noise Filtering';
            app.PeriodicNoiseRestorationMenu = uimenu(app.UIFigure);
            app.PeriodicNoiseRestorationMenu.MenuSelectedFcn = createCallbackFcn(app, @PeriodicNoiseRestorationMenuSelected, true);
            app.PeriodicNoiseRestorationMenu.Text = 'Periodic Noise Restoration';
            app.MotionBlurMenu = uimenu(app.UIFigure);
            app.MotionBlurMenu.MenuSelectedFcn = createCallbackFcn(app, @MotionBlurMenuSelected, true);
            app.MotionBlurMenu.Text = 'Motion Blur';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.DisplayGrid = uigridlayout(app.GridLayout);
            app.ResultsGrid = uigridlayout(app.DisplayGrid);
            app.ResultGrid5 = uigridlayout(app.ResultsGrid);
            app.UIAxes = uiaxes(app.ResultGrid5);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.Output5Label = uilabel(app.ResultGrid5);
            app.Output5Label.Visible = 'off';
            app.Output5Label.Text = 'Output 5';
            app.ResultGrid4 = uigridlayout(app.ResultsGrid);
            app.Output4Label = uilabel(app.ResultGrid4);
            app.Output4Label.Visible = 'off';
            app.Output4Label.Text = 'Output 4';
            app.ImageResult4 = uiimage(app.ResultGrid4);
            app.ImageResult4.Visible = 'off';
            app.ResultGrid3 = uigridlayout(app.ResultsGrid);
            app.ImageResult3 = uiimage(app.ResultGrid3);
            app.ImageResult3.Visible = 'off';
            app.Output3Label = uilabel(app.ResultGrid3);
            app.Output3Label.Visible = 'off';
            app.Output3Label.Text = 'Output 3';
            app.ResultGrid2 = uigridlayout(app.ResultsGrid);
            app.Output2Label = uilabel(app.ResultGrid2);
            app.Output2Label.Text = 'Result Image (Lib)';
            app.ImageResult2 = uiimage(app.ResultGrid2);
            app.ResultGrid1 = uigridlayout(app.ResultsGrid);
            app.Output1Label = uilabel(app.ResultGrid1);
            app.Output1Label.Text = 'Result Image';
            app.ImageResult1 = uiimage(app.ResultGrid1);
            app.InputGrid = uigridlayout(app.DisplayGrid);
            app.InputFourierGrid = uigridlayout(app.InputGrid);
            app.InputFourierLabel = uilabel(app.InputFourierGrid);
            app.InputFourierLabel.Text = 'Input Fourier Display';
            app.InputFourier = uiimage(app.InputFourierGrid);
            app.InputImageGrid = uigridlayout(app.InputGrid);
            app.InputImageLabel = uilabel(app.InputImageGrid);
            app.InputImageLabel.Text = 'Input Image';
            app.InputImage = uiimage(app.InputImageGrid);
            app.PanelGrid = uigridlayout(app.GridLayout);
            app.ConfigLabel = uilabel(app.PanelGrid);
            app.ConfigLabel.Text = 'Convolution';
            app.InputMaskEditFieldLabel = uilabel(app.PanelGrid);
            app.InputMaskEditFieldLabel.Text = 'Input Mask';
            app.InputMaskEditField = uieditfield(app.PanelGrid, 'text');
            app.InputMaskEditField.ValueChangedFcn = createCallbackFcn(app, @InputMaskEditFieldValueChanged, true);
            app.InfoMask = uilabel(app.PanelGrid);
            app.InfoMask.Text = 'Seperated by space e.g.: 0 1 0 1 2 1 0 1 0';
            app.BrowseButton = uibutton(app.PanelGrid, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse...';
            app.BrowseButtonLabel = uilabel(app.PanelGrid);
            app.BrowseButtonLabel.Text = 'Input Image';
            app.SelectedImageLabel = uilabel(app.PanelGrid);
            app.SelectedImageLabel.Text = 'No image has been chosen';
            app.SystemLogTextAreaLabel = uilabel(app.PanelGrid);
            app.SystemLogTextAreaLabel.Text = 'System Log';
            app.LogArea = uitextarea(app.PanelGrid);
            app.ExecuteButton = uibutton(app.PanelGrid, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.Input1EditFieldLabel = uilabel(app.PanelGrid);
            app.Input1EditFieldLabel.Visible = 'off';
            app.Input1EditFieldLabel.Text = 'Input 1';
            app.Input1EditField = uieditfield(app.PanelGrid, 'numeric');
            app.Input1EditField.ValueChangedFcn = createCallbackFcn(app, @Input1EditFieldValueChanged, true);
            app.Input1EditField.Visible = 'off';
            app.Input2EditFieldLabel = uilabel(app.PanelGrid);
            app.Input2EditFieldLabel.Visible = 'off';
            app.Input2EditFieldLabel.Text = 'Input 2';
            app.Input2EditField = uieditfield(app.PanelGrid, 'numeric');
            app.Input2EditField.ValueChangedFcn = createCallbackFcn(app, @Input2EditFieldValueChanged, true);
            app.Input2EditField.Visible = 'off';
            app.Input3EditField = uieditfield(app.PanelGrid, 'numeric');
            app.Input3EditField.Visible = 'off';
            app.Input3EditFieldLabel = uilabel(app.PanelGrid);
            app.Input3EditFieldLabel.Visible = 'off';
            app.Input3EditFieldLabel.Text = 'Input 3';
            app.DropDownLabel = uilabel(app.PanelGrid);
            app.DropDownLabel.Visible = 'off';
            app.DropDownLabel.Text = 'Drop Down';
            app.Type1DropDown = uidropdown(app.PanelGrid);
            app.Type1DropDown.ValueChangedFcn = createCallbackFcn(app, @Type1DropDownValueChanged, true);
            app.Type1DropDown.Visible = 'off';
            app.DropDown2Label = uilabel(app.PanelGrid);
            app.DropDown2Label.Visible = 'off';
            app.DropDown2Label.Text = 'Drop Down2';
            app.Type2DropDown = uidropdown(app.PanelGrid);
            app.Type2DropDown.ValueChangedFcn = createCallbackFcn(app, @Type2DropDownValueChanged, true);
            app.Type2DropDown.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = image_filtering

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end